classdef ImageEditing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NewFilenameEditField           matlab.ui.control.EditField
        NewFilenameEditFieldLabel      matlab.ui.control.Label
        UndoAllChagesButton            matlab.ui.control.Button
        ExportVideoButton              matlab.ui.control.Button
        TimeScrollbarSlider            matlab.ui.control.Slider
        TimeScrollbarSliderLabel       matlab.ui.control.Label
        BlueSlider                     matlab.ui.control.Slider
        BlueSliderLabel                matlab.ui.control.Label
        GreenSlider                    matlab.ui.control.Slider
        GreenSliderLabel               matlab.ui.control.Label
        RedSlider                      matlab.ui.control.Slider
        RedSliderLabel                 matlab.ui.control.Label
        BrowseButton                   matlab.ui.control.Button
        ChooseafiletoprocessEditField  matlab.ui.control.EditField
        ChooseafiletoprocessEditFieldLabel  matlab.ui.control.Label
        VideoDisplay                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Filename
    end
    
    methods (Access = private)
        
        function displayVideo(app)
            video = VideoReader(app.Filename);
            app.TimeScrollbarSlider.Limits = [0, video.Duration];
            app.TimeScrollbarSlider.MajorTicks = [0, video.Duration/2, video.Duration];
            app.TimeScrollbarSlider.MajorTickLabels = {num2str(0), num2str(round(video.Duration/2)), num2str(video.Duration)};
            
            while hasFrame(video)
                if(video.CurrentTime < video.Duration)
                    if app.TimeScrollbarSlider.Value == video.Duration
                        app.TimeScrollbarSlider.Value = app.TimeScrollbarSlider.Value - 1;
                    end
                    video.CurrentTime = app.TimeScrollbarSlider.Value;
                end
                frame = readFrame(video);
                r = frame(:,:,1) + app.RedSlider.Value;
                g = frame(:,:,2) + app.GreenSlider.Value;
                b = frame(:,:,3) + app.BlueSlider.Value;
                Image = cat(3, r, g, b);
                imshow(Image, "Parent", app.VideoDisplay);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            browsedFileName = uigetfile(["*.mp4","*.mkv"], "Select video");
            if browsedFileName ~= 0
                app.ChooseafiletoprocessEditField.Value = browsedFileName;
                app.Filename = browsedFileName;
                set(app.RedSlider, 'Enable', 'on')
                set(app.BlueSlider, 'Enable', 'on')
                set(app.GreenSlider, 'Enable', 'on')
                set(app.TimeScrollbarSlider, 'Enable', 'on')
                displayVideo(app);
            end
        end

        % Value changed function: TimeScrollbarSlider
        function TimeScrollbarSliderValueChanged(app, event)
            displayVideo(app);
        end

        % Value changed function: RedSlider
        function RedSliderValueChanged(app, event)
            displayVideo(app);       
        end

        % Value changed function: GreenSlider
        function GreenSliderValueChanged(app, event)
            displayVideo(app);
        end

        % Value changed function: BlueSlider
        function BlueSliderValueChanged(app, event)
            displayVideo(app);
        end

        % Button pushed function: UndoAllChagesButton
        function UndoAllChagesButtonPushed(app, event)
            app.RedSlider.Value = 0;
            app.GreenSlider.Value = 0;
            app.BlueSlider.Value = 0;
            displayVideo(app);
        end

        % Button pushed function: ExportVideoButton
        function ExportVideoButtonPushed(app, event)
            video = VideoReader(app.ChooseafiletoprocessEditField.Value);
            videoWriter = VideoWriter(app.NewFilenameEditField.Value);
            open(videoWriter);
            while hasFrame(video)
                frame = readFrame(video);
                r = frame(:,:,1) + app.RedSlider.Value;
                g = frame(:,:,2) + app.GreenSlider.Value;
                b = frame(:,:,3) + app.BlueSlider.Value;
                Image = cat(3, r, g, b);
                imageFrame = im2frame(Image);
                writeVideo(videoWriter, imageFrame);
            end
            close(videoWriter);
        end

        % Value changed function: NewFilenameEditField
        function NewFilenameEditFieldValueChanged(app, event)
            value = app.NewFilenameEditField.Value;
            if value ~= 0
                set(app.ExportVideoButton, 'Enable', 'on');
            end
        end

        % Value changed function: ChooseafiletoprocessEditField
        function ChooseafiletoprocessEditFieldValueChanged(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VideoDisplay = uiaxes(app.UIFigure);
            title(app.VideoDisplay, 'Video Display')
            zlabel(app.VideoDisplay, 'Z')
            app.VideoDisplay.XTick = [];
            app.VideoDisplay.YTick = [];
            app.ChooseafiletoprocessEditFieldLabel = uilabel(app.UIFigure);
            app.ChooseafiletoprocessEditFieldLabel.Text = 'Choose a file to process';
            app.ChooseafiletoprocessEditField = uieditfield(app.UIFigure, 'text');
            app.ChooseafiletoprocessEditField.ValueChangedFcn = createCallbackFcn(app, @ChooseafiletoprocessEditFieldValueChanged, true);
            app.ChooseafiletoprocessEditField.Editable = 'off';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.RedSliderLabel = uilabel(app.UIFigure);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.UIFigure);
            app.RedSlider.Limits = [-50 50];
            app.RedSlider.MajorTicks = [];
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderValueChanged, true);
            app.RedSlider.Enable = 'off';
            app.GreenSliderLabel = uilabel(app.UIFigure);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.UIFigure);
            app.GreenSlider.Limits = [-50 50];
            app.GreenSlider.MajorTicks = [];
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderValueChanged, true);
            app.GreenSlider.Enable = 'off';
            app.BlueSliderLabel = uilabel(app.UIFigure);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.UIFigure);
            app.BlueSlider.Limits = [-50 50];
            app.BlueSlider.MajorTicks = [];
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderValueChanged, true);
            app.BlueSlider.Enable = 'off';
            app.TimeScrollbarSliderLabel = uilabel(app.UIFigure);
            app.TimeScrollbarSliderLabel.Text = 'Time Scrollbar';
            app.TimeScrollbarSlider = uislider(app.UIFigure);
            app.TimeScrollbarSlider.Limits = [0 1];
            app.TimeScrollbarSlider.MajorTicks = [0 0.5 1];
            app.TimeScrollbarSlider.ValueChangedFcn = createCallbackFcn(app, @TimeScrollbarSliderValueChanged, true);
            app.TimeScrollbarSlider.MinorTicks = [];
            app.TimeScrollbarSlider.Enable = 'off';
            app.ExportVideoButton = uibutton(app.UIFigure, 'push');
            app.ExportVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ExportVideoButtonPushed, true);
            app.ExportVideoButton.Enable = 'off';
            app.ExportVideoButton.Text = 'Export Video';
            app.UndoAllChagesButton = uibutton(app.UIFigure, 'push');
            app.UndoAllChagesButton.ButtonPushedFcn = createCallbackFcn(app, @UndoAllChagesButtonPushed, true);
            app.UndoAllChagesButton.Enable = 'off';
            app.UndoAllChagesButton.Text = 'Undo All Chages';
            app.NewFilenameEditFieldLabel = uilabel(app.UIFigure);
            app.NewFilenameEditFieldLabel.Text = 'New Filename';
            app.NewFilenameEditField = uieditfield(app.UIFigure, 'text');
            app.NewFilenameEditField.ValueChangedFcn = createCallbackFcn(app, @NewFilenameEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageEditing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end